classdef ELEC_291_Project_1_Reflow_Oven_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ErrorTimeoutsSlider           matlab.ui.control.Slider
        ErrorTimeoutsSliderLabel      matlab.ui.control.Label
        Spinner_5                     matlab.ui.control.Spinner
        ReflowTimesSlider             matlab.ui.control.Slider
        ReflowTimesSliderLabel        matlab.ui.control.Label
        Spinner_4                     matlab.ui.control.Spinner
        ReflowTemperatureSlider       matlab.ui.control.Slider
        ReflowTemperatureSliderLabel  matlab.ui.control.Label
        Spinner_3                     matlab.ui.control.Spinner
        SoakingTimesSlider            matlab.ui.control.Slider
        SoakingTimeLabel              matlab.ui.control.Label
        Spinner_2                     matlab.ui.control.Spinner
        SoakingTemperatureSlider      matlab.ui.control.Slider
        SampleCountLabel              matlab.ui.control.Label
        Spinner                       matlab.ui.control.Spinner
        Lamp                          matlab.ui.control.Lamp
        ShihLingShen84722297Label     matlab.ui.control.Label
        OPENButton                    matlab.ui.control.Button
        TextArea                      matlab.ui.control.TextArea
        STARTButton                   matlab.ui.control.Button
        ABORTButton                   matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        BuadRateEditField             matlab.ui.control.NumericEditField
        BuadRateEditFieldLabel        matlab.ui.control.Label
        COMPortDropDown               matlab.ui.control.DropDown
        COMPortDropDownLabel          matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        serialportopen % Description
        currenttemp % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            a = plot(app.UIAxes,1,1);
            delete(a);
            if app.serialportopen ~= 0
                app.STARTButton.Enable = 'off';
                app.STARTButton.Text = 'Running...';
                app.UnitsDropDown.Enable = 'off';
                flush(app.serialportopen);
                x = 1:1000000;
                y = 0*(1:1000000);
                z = 0*(1:1000000);
                %hLine = plot(app.UIAxes,x,y);
                aLine = plot(app.UIAxes,x,z);
                for i=1:1000000
                    if (strfind(app.UnitsDropDown.Value,'Celsius') ~= 0)
                        y(i) = fscanf(app.serialportopen,"%d")/100.00;
                    end
                    if (strfind(app.UnitsDropDown.Value,'Kelvin') ~= 0)
                        y(i) = fscanf(app.serialportopen,"%d")/100.00+273.15;
                    end
                    if (strfind(app.UnitsDropDown.Value,'Fahrenheit ') ~= 0)
                        y(i) = ((fscanf(app.serialportopen,"%d"))*9.0/500.0+32.00);
                    end
                    for q = max(1,i-app.SoakingTemperatureSlider.Value):i
                        z(i) = z(i)+y(q);
                    end
                    z(i)=z(i)/(i-max(1,i-app.SoakingTemperatureSlider.Value)+1);
	                %stripchart('Update',hLine,y(i))
                    stripchart('Update',aLine,z(i))
                end
            end
        end

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            if (strfind(app.COMPortDropDown.Value,'COM') ~= 0)
                if (app.BuadRateEditField.Value > 0)
                    app.serialportopen = serialport(app.COMPortDropDown.Value,app.BuadRateEditField.Value);
                    app.TextArea.Value = {'COM Port opened sucessfully.', 'Use the ABORT button to close COM Port.'};
                    app.OPENButton.Enable = 'off';
                    app.RefreshButton.Enable = 'off';
                    app.COMPortDropDown.Enable = 'off';
                    app.BuadRateEditField.Enable = 'off';
                    app.STARTButton.Enable = 'on';
                    app.STARTButton.Visible = 'on';
                    app.ABORTButton.Enable = 'on';
                    app.ABORTButton.Visible = 'on';
                else
                    app.TextArea.Value = {'Baud rate cannot be negative.'};
                end
            else
                app.TextArea.Value = {'No valid COM Port found.'};
            end
        end

        % Button pushed function: ABORTButton
        function ABORTButtonPushed(app, event)
            a = plot(app.UIAxes,1,1);
            delete(a);
            flush(app.serialportopen);
            app.serialportopen = 0;
            app.OPENButton.Enable = 'on';
            app.RefreshButton.Enable = 'on';
            app.COMPortDropDown.Enable = 'on';
            app.BuadRateEditField.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.STARTButton.Visible = 'off';
            app.STARTButton.Text = 'START';
            app.ABORTButton.Enable = 'off';
            app.ABORTButton.Visible = 'off';
            app.UnitsDropDown.Enable = 'on';
            app.TextArea.Value = {'No COM port opened.'};
            app.COMPortDropDown.Items = serialportlist("available");
            app.COMPortDropDown.Items(end+1) = {'NONE'};
        end

        % Callback function
        function AveragingCheckBoxValueChanged(app, event)
            value = app.AveragingCheckBox.Value;
            if value ~= 0
                app.SoakingTemperatureSlider.Visible = 'on';
                app.SampleCountLabel.Visible = 'on';
                app.Spinner.Visible = 'on';
            else
                app.SoakingTemperatureSlider.Visible = 'off';
                app.SampleCountLabel.Visible = 'off';
                app.Spinner.Visible = 'off';
                app.SoakingTemperatureSlider.Value = 0;
                app.Spinner.Value = 0;
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.SoakingTemperatureSlider.Value = app.Spinner.Value;
        end

        % Value changed function: SoakingTemperatureSlider
        function SoakingTemperatureSliderValueChanged(app, event)
            app.SoakingTemperatureSlider.Value = round(app.SoakingTemperatureSlider.Value);
            app.Spinner.Value = app.SoakingTemperatureSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature Plot')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [999900 1000000];
            app.UIAxes.XTickLabel = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Temperature Plot')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Temperature')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [999900 1000000];
            app.UIAxes_2.XTickLabel = '';
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {''};
            app.COMPortDropDown.Value = '';
            app.BuadRateEditFieldLabel = uilabel(app.UIFigure);
            app.BuadRateEditFieldLabel.Text = 'Buad Rate';
            app.BuadRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BuadRateEditField.ValueDisplayFormat = '%11.4d';
            app.BuadRateEditField.Value = 115200;
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ABORTButton = uibutton(app.UIFigure, 'push');
            app.ABORTButton.ButtonPushedFcn = createCallbackFcn(app, @ABORTButtonPushed, true);
            app.ABORTButton.Text = 'ABORT';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'No COM port opened.'};
            app.OPENButton = uibutton(app.UIFigure, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.ShihLingShen84722297Label = uilabel(app.UIFigure);
            app.ShihLingShen84722297Label.Text = {'ELEC 291 - Electrical Engineering Design Studio I'; 'Project 1 - Reflow Oven Controller'; '2024.'};
            app.Lamp = uilamp(app.UIFigure);
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [0 400];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.SampleCountLabel = uilabel(app.UIFigure);
            app.SampleCountLabel.Text = {'Soaking'; 'Temperature'};
            app.SoakingTemperatureSlider = uislider(app.UIFigure);
            app.SoakingTemperatureSlider.Limits = [0 400];
            app.SoakingTemperatureSlider.ValueChangedFcn = createCallbackFcn(app, @SoakingTemperatureSliderValueChanged, true);
            app.Spinner_2 = uispinner(app.UIFigure);
            app.Spinner_2.Limits = [0 120];
            app.SoakingTimeLabel = uilabel(app.UIFigure);
            app.SoakingTimeLabel.Text = {'Soaking'; 'Time (s)'};
            app.SoakingTimesSlider = uislider(app.UIFigure);
            app.SoakingTimesSlider.Limits = [0 120];
            app.Spinner_3 = uispinner(app.UIFigure);
            app.Spinner_3.Limits = [0 400];
            app.ReflowTemperatureSliderLabel = uilabel(app.UIFigure);
            app.ReflowTemperatureSliderLabel.Text = {'Reflow'; 'Temperature'};
            app.ReflowTemperatureSlider = uislider(app.UIFigure);
            app.ReflowTemperatureSlider.Limits = [0 400];
            app.Spinner_4 = uispinner(app.UIFigure);
            app.Spinner_4.Limits = [0 120];
            app.ReflowTimesSliderLabel = uilabel(app.UIFigure);
            app.ReflowTimesSliderLabel.Text = {'Reflow'; 'Time (s)'};
            app.ReflowTimesSlider = uislider(app.UIFigure);
            app.ReflowTimesSlider.Limits = [0 120];
            app.Spinner_5 = uispinner(app.UIFigure);
            app.Spinner_5.Limits = [0 50];
            app.ErrorTimeoutsSliderLabel = uilabel(app.UIFigure);
            app.ErrorTimeoutsSliderLabel.Text = {'Error'; 'Timeout (s)'};
            app.ErrorTimeoutsSlider = uislider(app.UIFigure);
            app.ErrorTimeoutsSlider.Limits = [0 120];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ELEC_291_Project_1_Reflow_Oven_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end